classdef SatUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        DateTextArea                matlab.ui.control.TextArea
        DateTextAreaLabel           matlab.ui.control.Label
        DegLabel_3                  matlab.ui.control.Label
        El_maskSpinner              matlab.ui.control.Spinner
        El_maskSpinnerLabel         matlab.ui.control.Label
        KmLabel                     matlab.ui.control.Label
        HeightSpinner               matlab.ui.control.Spinner
        HeightSpinnerLabel          matlab.ui.control.Label
        DegLabel_2                  matlab.ui.control.Label
        DegLabel                    matlab.ui.control.Label
        LatitudeSpinner             matlab.ui.control.Spinner
        LatitudeSpinnerLabel        matlab.ui.control.Label
        LongitudeSpinner            matlab.ui.control.Spinner
        LongitudeSpinnerLabel       matlab.ui.control.Label
        SatelliteSystemButtonGroup  matlab.ui.container.ButtonGroup
        BDSCheckBox                 matlab.ui.control.CheckBox
        QZSSCheckBox                matlab.ui.control.CheckBox
        GPSCheckBox                 matlab.ui.control.CheckBox
        EpochProgressSlider         matlab.ui.control.Slider
        EpochProgressSliderLabel    matlab.ui.control.Label
        GroundTrackPanel            matlab.ui.container.Panel
        SkyPlotPanel                matlab.ui.container.Panel
        StartButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        GPS
        QZSS
        BDS
        Ground
        is_gps_true
        is_qzss_true
        is_bds_true
        gx
        el_mask
        des_timestep
        timestep
    end
    
    methods (Access = private)
        
        function Setground(app)
            app.GPS = app.GPS.Set_enu(app.Ground);
            app.QZSS = app.QZSS.Set_enu(app.Ground);
            app.BDS = app.BDS.Set_enu(app.Ground);
        end
        
        function Skyplotting(app)
            des_target = [];
            if app.is_gps_true
                az = azimuth(app.GPS.enudataset);
                el = elevation(app.GPS.enudataset, app.el_mask);
                if ~isnan(el(app.des_timestep+1)) | ~isnan(az(app.des_timestep+1))
                    des_target = [des_target;az(app.des_timestep+1) el(app.des_timestep+1), 0];
                end 
            end
             
            if app.is_qzss_true
                az = azimuth(app.QZSS.enudataset);
                el = elevation(app.QZSS.enudataset, app.el_mask);
                if ~isnan(el(app.des_timestep+1))
                    des_target = [des_target;az(app.des_timestep+1) el(app.des_timestep+1), 1];
                end
            end
            
            if app.is_bds_true
                az = azimuth(app.BDS.enudataset);
                el = elevation(app.BDS.enudataset, app.el_mask);
                if ~isnan(el(app.des_timestep+1))
                    des_target = [des_target;az(app.des_timestep+1) el(app.des_timestep+1), 2];
                end
            end

            if ~(app.is_gps_true | app.is_qzss_true | app.is_bds_true)
                skyplot(app.SkyPlotPanel, NaN, NaN)
            else
                if ~isempty(des_target)
                    skyplot(app.SkyPlotPanel,des_target(:,1),des_target(:,2),GroupData=categorical(des_target(:,3),[0,1,2],{'GPS', 'QZSS', 'BDS'}))
                end
            end
        end
        
        function Geoplotting(app)
            delete(app.gx)
            app.gx = geoaxes(app.GroundTrackPanel);
            hold(app.gx, "on")
            if app.is_gps_true
                geoplot(app.gx, app.GPS.geodataset(:,1), app.GPS.geodataset(:,2), 'bo', 'markerSize', 1);
            end

            if app.is_qzss_true
                geoplot(app.gx, app.QZSS.geodataset(:,1), app.QZSS.geodataset(:,2), 'o', 'markerSize', 1, 'Color', "#D95319");
            end

            if app.is_bds_true
                geoplot(app.gx, app.BDS.geodataset(:,1), app.BDS.geodataset(:,2), 'o', 'markerSize', 1, 'Color', "#EDB120");
            end

            geoplot(app.gx, app.Ground(1), app.Ground(2), "hexagram", "markerSize", 15,"Color","#7E2F8E", 'MarkerFaceColor',"#7E2F8E")
        end
        
        function MovingGeo(app)
            if rem(app.des_timestep,144) == 0
                app.Geoplotting()
            end

            if app.is_gps_true
                geoplot(app.gx, app.GPS.geodataset(app.des_timestep,1), app.GPS.geodataset(app.des_timestep,2), 'bhexagram', 'MarkerSize',5)
            end

            if app.is_qzss_true
                geoplot(app.gx, app.QZSS.geodataset(app.des_timestep,1), app.QZSS.geodataset(app.des_timestep,2), 'hexagram','Color', "#D95319", 'MarkerSize',5)
            end

            if app.is_bds_true
                geoplot(app.gx, app.BDS.geodataset(app.des_timestep,1), app.BDS.geodataset(app.des_timestep,2), 'hexagram', 'Color', "#EDB120", 'MarkerSize',5)
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load("nav.mat");
            app.timestep = 600;
            app.GPS = Satellite(nav.GPS, app.timestep);
            app.QZSS = Satellite(nav.QZSS, app.timestep);
            app.BDS = Satellite(nav.BDS, app.timestep);
            app.Ground = [37,128.128,1];
            app.is_gps_true = 0;
            app.is_qzss_true = 0;
            app.is_bds_true = 0;
            app.GPSCheckBox.Value = 0;
            app.BDSCheckBox.Value = 0;
            app.QZSSCheckBox.Value = 0;
            app.el_mask = 10;
            app.des_timestep = 0;
            app.DateTextArea.Value = string(datetime([2023 06 01 12 00 00])+minutes(10)*app.des_timestep);
            app.Setground()
            app.Skyplotting()
            app.Geoplotting()
        end

        % Callback function: El_maskSpinner, El_maskSpinner, HeightSpinner,
        % 
        % ...and 5 other components
        function SpinnerValueChanged(app, event)
            app.Ground(1) = app.LatitudeSpinner.Value;
            app.Ground(2) = app.LongitudeSpinner.Value;
            app.Ground(3) = app.HeightSpinner.Value;
            app.el_mask = app.El_maskSpinner.Value;
            app.Setground()
            app.Skyplotting()
            app.Geoplotting()
        end

        % Value changed function: BDSCheckBox, GPSCheckBox, QZSSCheckBox
        function CheckBoxValueChanged(app, event)
            app.is_gps_true = app.GPSCheckBox.Value;
            app.is_bds_true = app.BDSCheckBox.Value;
            app.is_qzss_true = app.QZSSCheckBox.Value;
            app.Skyplotting()
            app.Geoplotting()
        end

        % Callback function: EpochProgressSlider, EpochProgressSlider
        function EpochProgressSliderValueChanged(app, event)
            app.des_timestep = round(app.EpochProgressSlider.Value);
            app.DateTextArea.Value = string(datetime([2023 06 01 12 00 00])+minutes(10)*app.des_timestep);
            app.Skyplotting()
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.StartButton.Text == "Stop"
                app.startupFcn()
                return
            end
            Allaz = [];
            Allel = [];
            if app.is_gps_true
                az = azimuth(app.GPS.enudataset);
                el = elevation(app.GPS.enudataset, 0);
                Allaz = [Allaz,[0;az']];
                Allel = [Allel,[0;el']];
            end 

            if app.is_qzss_true
                az = azimuth(app.QZSS.enudataset);
                el = elevation(app.QZSS.enudataset, 0);
                Allaz = [Allaz, [1;az']];
                Allel = [Allel,[1;el']];
            end

            if app.is_bds_true
                az = azimuth(app.BDS.enudataset);
                el = elevation(app.BDS.enudataset, 0);
                Allaz = [Allaz, [2;az']];
                Allel = [Allel,[2;el']];
            end

            Allel(Allel<app.el_mask) = missing;
            if ~isempty(Allaz)
                app.StartButton.Text = "Stop";
                if size(Allaz,2) == 1
                    Allaz = [Allaz,[2;NaN(size(Allaz,1)-1,1)]];
                    Allel = [Allel,[2;NaN(size(Allel,1)-1,1)]];
                end
                sp = skyplot(app.SkyPlotPanel,Allaz(2,:), Allel(2,:), GroupData=categorical(Allaz(1,:),[0,1,2],{'GPS', 'QZSS', 'BDS'}));
                for Time = 2:size(Allaz,1)
                    try
                        set(sp, AzimuthData=Allaz(2:Time,:), ElevationData=Allel(2:Time,:))
                        app.des_timestep = Time-1;
                        app.DateTextArea.Value = string(datetime([2023 06 01 12 00 00])+minutes(10)*app.des_timestep);
                        app.EpochProgressSlider.Value = app.des_timestep;
                        app.MovingGeo()
                        pause(0.05)
                        drawnow limitrate
                    catch
                        break
                    end
                end
                app.StartButton.Text = "Start";
                app.des_timestep = 0;
                app.DateTextArea.Value = string(datetime([2023 06 01 12 00 00])+minutes(10)*app.des_timestep);
                app.EpochProgressSlider.Value = app.des_timestep;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SkyPlotPanel = uipanel(app.UIFigure);
            app.SkyPlotPanel.Title = 'Sky Plot';
            app.GroundTrackPanel = uipanel(app.UIFigure);
            app.GroundTrackPanel.Title = 'Ground Track';
            app.EpochProgressSliderLabel = uilabel(app.UIFigure);
            app.EpochProgressSliderLabel.Text = 'Epoch Progress';
            app.EpochProgressSlider = uislider(app.UIFigure);
            app.EpochProgressSlider.Limits = [0 721];
            app.EpochProgressSlider.MajorTicks = [0 144 288 432 576 721];
            app.EpochProgressSlider.MajorTickLabels = {'Day-0', 'Day-1', 'Day-2', 'Day-3', 'Day-4', 'Day-5'};
            app.EpochProgressSlider.ValueChangedFcn = createCallbackFcn(app, @EpochProgressSliderValueChanged, true);
            app.EpochProgressSlider.ValueChangingFcn = createCallbackFcn(app, @EpochProgressSliderValueChanged, true);
            app.EpochProgressSlider.MinorTicks = [0 9 18 27 36 45 54 63 72 81 90 99 108 117 126 135 144 153 162 171 180 189 198 207 216 225 234 243 252 261 270 279 288 297 306 315 324 333 342 351 360 369 378 387 396 405 414 423 432 441 450 459 468 477 486 495 504 513 522 531 540 549 558 567 576 585 594 603 612 621 630 639 648 657 666 675 684 693 702 711 720 730];
            app.SatelliteSystemButtonGroup = uibuttongroup(app.UIFigure);
            app.SatelliteSystemButtonGroup.Title = 'Satellite System';
            app.GPSCheckBox = uicheckbox(app.SatelliteSystemButtonGroup);
            app.GPSCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.GPSCheckBox.Text = 'GPS';
            app.QZSSCheckBox = uicheckbox(app.SatelliteSystemButtonGroup);
            app.QZSSCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.QZSSCheckBox.Text = 'QZSS';
            app.BDSCheckBox = uicheckbox(app.SatelliteSystemButtonGroup);
            app.BDSCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.BDSCheckBox.Text = 'BDS';
            app.LongitudeSpinnerLabel = uilabel(app.UIFigure);
            app.LongitudeSpinnerLabel.Text = 'Longitude';
            app.LongitudeSpinner = uispinner(app.UIFigure);
            app.LongitudeSpinner.ValueChangingFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.LongitudeSpinner.Limits = [0 360];
            app.LongitudeSpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.LongitudeSpinner.Value = 128.128;
            app.LatitudeSpinnerLabel = uilabel(app.UIFigure);
            app.LatitudeSpinnerLabel.Text = 'Latitude';
            app.LatitudeSpinner = uispinner(app.UIFigure);
            app.LatitudeSpinner.ValueChangingFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.LatitudeSpinner.Limits = [-90 90];
            app.LatitudeSpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.LatitudeSpinner.Value = 37;
            app.DegLabel = uilabel(app.UIFigure);
            app.DegLabel.Text = 'Deg';
            app.DegLabel_2 = uilabel(app.UIFigure);
            app.DegLabel_2.Text = 'Deg';
            app.HeightSpinnerLabel = uilabel(app.UIFigure);
            app.HeightSpinnerLabel.Text = 'Height';
            app.HeightSpinner = uispinner(app.UIFigure);
            app.HeightSpinner.ValueChangingFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.HeightSpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.HeightSpinner.Value = 1;
            app.KmLabel = uilabel(app.UIFigure);
            app.KmLabel.Text = 'Km';
            app.El_maskSpinnerLabel = uilabel(app.UIFigure);
            app.El_maskSpinnerLabel.Text = 'El_mask';
            app.El_maskSpinner = uispinner(app.UIFigure);
            app.El_maskSpinner.ValueChangingFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.El_maskSpinner.Limits = [0 90];
            app.El_maskSpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.El_maskSpinner.Value = 10;
            app.DegLabel_3 = uilabel(app.UIFigure);
            app.DegLabel_3.Text = 'Deg';
            app.DateTextAreaLabel = uilabel(app.UIFigure);
            app.DateTextAreaLabel.Text = 'Date';
            app.DateTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SatUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end